classdef Quantum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        HomeTab                      matlab.ui.container.Tab
        SharedKeyEditField           matlab.ui.control.EditField
        SharedKeyEditFieldLabel      matlab.ui.control.Label
        CopyButton_2                 matlab.ui.control.Button
        CopyButton                   matlab.ui.control.Button
        PrivateKeyEditField          matlab.ui.control.EditField
        PrivateKeyEditFieldLabel     matlab.ui.control.Label
        PublicKeyEditField           matlab.ui.control.EditField
        PublicKeyEditFieldLabel      matlab.ui.control.Label
        DownloadDataButton           matlab.ui.control.Button
        GenerateSharedkeyButton      matlab.ui.control.Button
        GeneratekeysButton           matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        StoredataTab                 matlab.ui.container.Tab
        uploadtocloudButton          matlab.ui.control.Button
        ListBox_2                    matlab.ui.control.ListBox
        encryptButton                matlab.ui.control.Button
        ListBox                      matlab.ui.control.ListBox
        uploadfileButton             matlab.ui.control.Button
        EntersharedkeyTextArea       matlab.ui.control.TextArea
        EntersharedkeyTextAreaLabel  matlab.ui.control.Label
        DownloaddataTab              matlab.ui.container.Tab
        localdriveButton             matlab.ui.control.Button
        ListBox_5                    matlab.ui.control.ListBox
        DownloadFileButton           matlab.ui.control.Button
        ListBox_4                    matlab.ui.control.ListBox
        DecryptButton                matlab.ui.control.StateButton
        ListBox_3                    matlab.ui.control.ListBox
        EnterSharedKeyTextArea       matlab.ui.control.TextArea
        EnterSharedKeyTextAreaLabel  matlab.ui.control.Label
        SelectfileButton             matlab.ui.control.Button
        DataManagementTab            matlab.ui.container.Tab
        DisplayDataButton            matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        KeyManagementTab             matlab.ui.container.Tab
        AccountSettingsTab           matlab.ui.container.Tab
        NavmenubarDropDown           matlab.ui.control.DropDown
        NavmenubarDropDownLabel      matlab.ui.control.Label
        DASHBOARDLabel               matlab.ui.control.Label
    end

   properties (Access = public)
    SelectedFileName = ''; % Initialize SelectedFileName with an empty string
    SelectedFilePath = ''; % Initialize SelectedFilePath with an empty string
   end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: NavmenubarDropDown, NavmenubarDropDown
        function NavmenubarDropDownValueChanged(app, event)
            % Get the selected dropdown option
    value = app.NavmenubarDropDown.Value;
    
    % Switch to the corresponding tab/page based on the selected option
    switch value
        case 'Home'
            app.TabGroup.SelectedTab = app.HomeTab;
            % Code to switch to the Home tab/page
        case 'Data Management'
            app.TabGroup.SelectedTab = app.DataManagementTab;
            % Code to switch to the Data Management tab/page
        case 'Key Management'
             app.TabGroup.SelectedTab = app.KeyManagementTab;
            % Code to switch to the Key Management tab/page
        case 'Account Settings'
             app.TabGroup.SelectedTab = app.AccountSettingsTab;
            % Code to switch to the Account Settings tab/page
        
    end
        end

        % Button pushed function: GeneratekeysButton
        function GeneratekeysButtonPushed(app, event)
          % Button pushed function: SelectfileButton
 
        end

        % Button pushed function: uploadfileButton
        function uploadfileButtonPushed(app, event)
             [file, path] = uigetfile({'*.*'}, 'Select a file to upload');
        if isequal(file, 0)
        disp('User selected Cancel');
          else
        % Add the file at the beginning of the list or replace the first item
        % Check if the list is empty or not
        if isempty(app.ListBox.Items)
            % If the list is empty, directly add the file
            app.ListBox.Items = file;
        else
            % If the list is not empty, insert the file at the beginning
            app.ListBox.Items = [{file}, app.ListBox.Items];
        end
        
        % Select the newly added item
        app.ListBox.Value = file;
        
        % Store file info
        app.ListBox.UserData = struct('path', path, 'file', file);


                    disp('User selected ');
        end
           
        end

        % Button pushed function: encryptButton
        function encryptButtonPushed(app, event)
        % Get the shared key from the UI
    sharedKey = app.EntersharedkeyTextArea.Value;

    % Check if a file has been selected from ListBox
    if isempty(app.ListBox.Value)
        uialert(app.UIFigure, 'Please upload a file first.', 'File Needed');
        return;
    end

    % Get the selected file from the list and its full file path
    selectedFile = app.ListBox.Value;
    filePath = fullfile(app.ListBox.UserData.path, selectedFile);

    % Read the content of the image file as a uint8 array
    fileContent = imread(filePath);

    % Encrypt the image content using the shared key
    encryptedContent = simpleABEEncrypt(app, fileContent, sharedKey);

    % Write the encrypted content back to a file
    encryptedFilePath = [filePath '.enc']; % Save as a new '.enc' file
    imwrite(encryptedContent, encryptedFilePath, 'png'); % Specify the file format as PNG

    % Store the path of the encrypted file in ListBox_2 UserData
    app.ListBox_2.UserData.path = app.ListBox.UserData.path;
    app.ListBox_2.UserData.encryptedFilePath = encryptedFilePath;

    % Update ListBox_2 to display the encrypted file
    if isempty(app.ListBox_2.Items)
        % If ListBox_2 is empty, directly add the encrypted file
        app.ListBox_2.Items = {[selectedFile ' (encrypted)']};
    else
        % If ListBox_2 is not empty, insert the encrypted file at the beginning
        app.ListBox_2.Items = [{[selectedFile ' (encrypted)']}, app.ListBox_2.Items];
    end

    % Select the newly added encrypted file
    app.ListBox_2.Value = {[selectedFile ' (encrypted)']};
end

function encryptedData = simpleABEEncrypt(~, fileContent, sharedKey)
    % Ensure sharedKey is a character array, not a cell
    if iscell(sharedKey)
        sharedKey = sharedKey{1}; % Extract the string from the cell
    end
    numericKey = sum(double(sharedKey));

    % XOR the file content with the combined key for a simple encryption
    encryptedData = bitxor(fileContent, uint8(mod(numericKey, 256))); % Adjusted for character data
        
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)

        % Get file list from S3 bucket
    bucketName = 'shaakthi'; % Replace with your S3 bucket name
    command = 'aws s3 ls s3://shaakthi/';
    [status, fileList] = system(command);
    if status ~= 0
        disp('Error listing files in S3 bucket');
        return;
    end

    % Split the file list into individual lines
    fileList = strsplit(fileList, newline);
    
    % Extract file names without timestamps
    fileNames = {};
    for i = 1:numel(fileList)
        % Split each line by whitespace and get the last part (file name)
        parts = strsplit(fileList{i}, ' ');
        fileName = parts{end};
        
        % Remove leading and trailing whitespace
        fileName = strtrim(fileName);
        
        % Add to the list of file names
        fileNames{i} = fileName;
    end
    
    % Remove empty elements
    fileNames = fileNames(~cellfun('isempty', fileNames));

    % Display the list of files to the user
    if isempty(fileNames)
        disp('No files found in the S3 bucket');
        return;
    end

    % Create a cell array to store file names with an additional 'Cancel' option
    fileListWithCancel = [{'Cancel'}, fileNames];

    % Display a dialog box for the user to select a file
   [selectedFileIndex, isSelected] = listdlg('PromptString', 'Select a file to decrypt:', ...
    'SelectionMode', 'single', 'ListString', fileListWithCancel, 'Name', 'Select File');

    % Check if the user canceled the selection or no file was selected
    if ~isSelected || selectedFileIndex == 1
        return; % User canceled selection or no file was selected
    end

    % Get the selected file name
    selectedFileName = fileNames{selectedFileIndex - 1};

    % Update ListBox_3 to display the selected file name
    app.ListBox_3.Items = {selectedFileName};

    % Store the selected file URI
    s3FilePath = ['s3://', bucketName, '/', selectedFileName];
    app.ListBox_3.UserData.s3URI = s3FilePath;

    % Store the local folder path where the file will be downloaded
    app.SelectedFilePath = fullfile('/Quantum safe/'); % Set your local folder path

    % Store the selected file name
    app.SelectedFileName = selectedFileName;


        end

        % Value changed function: DecryptButton
        function DecryptButtonValueChanged(app, event)
        % Get the shared key from the UI
    sharedKey = app.EnterSharedKeyTextArea.Value;
    
    % Get the selected filename from ListBox_4
    selectedFileName = app.ListBox_4.Value;
    
    % Check if a file has been selected
    if isempty(selectedFileName)
        uialert(app.UIFigure, 'Please select a file for decryption.', 'File Needed');
        return;
    end
    
    % Construct the full file path
    selectedFilePath = fullfile(app.SelectedFilePath, selectedFileName);
    
    % Debug print to verify the selected file path
    disp(['Selected file path for decryption: ', selectedFilePath]);
    
    % Check if the file exists
    if ~isfile(selectedFilePath)
        uialert(app.UIFigure, ['File does not exist: ', selectedFilePath], 'File Not Found');
        return;
    end
    
    % Check if the file is an encrypted image file
    [~, name, ext] = fileparts(selectedFilePath);
    if ~strcmpi(ext, '.enc')
        uialert(app.UIFigure, 'Selected file is not an encrypted file.', 'Error');
        return;
    end

    try
        % Decrypt the encrypted content
        decryptedContent = simpleABEDecrypt(selectedFilePath, sharedKey);

        % Remove the existing .enc extension from the filename
        if endsWith(name, '.enc')
            name = name(1:end-4);
        end

        % Save the decrypted content to a new file
        decryptedFileName = [name '_decrypted.png']; % Assuming PNG format
        decryptedFilePath = fullfile(app.SelectedFilePath, decryptedFileName);
        imwrite(decryptedContent, decryptedFilePath);

        % Update ListBox_5 to display the decrypted file
        app.ListBox_5.Items = {decryptedFileName};

    catch exception
        % Display error message if decryption fails
        uialert(app.UIFigure, ['Failed to decrypt the file: ', exception.message], 'Error');
    end

    % Nested function for decryption
    function decryptedContent = simpleABEDecrypt(filePath, sharedKey)
        if iscell(sharedKey) && ~isempty(sharedKey)
            sharedKey = sharedKey{1};
        end

        % Read the encrypted content of the file
        fileID = fopen(filePath, 'rb');
        encryptedContent = fread(fileID, '*uint8');
        fclose(fileID);

        numericKey = sum(double(sharedKey));

        % Adjusted XOR operation for decryption, assuming encryptedContent is uint8
        decryptedData = bitxor(encryptedContent, uint8(mod(numericKey, 256)));

        % Here, we assume the output is expected to be binary data.
        decryptedContent = uint8(decryptedData);
    end
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
          clipboard('copy', app.PublicKeyEditField.Value);   
        end

        % Button pushed function: CopyButton_2
        function CopyButton_2Pushed(app, event)
        clipboard('copy', app.PrivateKeyEditField.Value);   
        end

        % Button pushed function: GenerateSharedkeyButton
        function GenerateSharedkeyButtonPushed(app, event)
        % Update the UI with generated keys for the user
[app,userPublicKey, userPrivateKey] = generateHashBasedKeys();  % Generate keys

app.PublicKeyEditField.Value = userPublicKey;
app.PrivateKeyEditField.Value = userPrivateKey;

% Define the GenerateSharedkeyButtonPushed function
function GenerateSharedkeyButtonPushed(app, event)
    % Fetch the user's private key and data holder's public key from the app
    userPrivateKeyHex = app.PrivateKeyEditField.Value;
    dataHolderPublicKeyHex = app.PublicKeyEditField.Value;

    % Check if the keys are not empty
    if isempty(userPrivateKeyHex) || isempty(dataHolderPublicKeyHex)
        errordlg('Keys are missing. Generate keys first.');
        return;
    end

    % Convert hexadecimal strings to numeric arrays
    userPrivateKeyBin = hexToBinaryVector(userPrivateKeyHex);
    dataHolderPublicKeyBin = hexToBinaryVector(dataHolderPublicKeyHex);

    % Ensure both keys have the same length
    assert(length(userPrivateKeyBin) == length(dataHolderPublicKeyBin), ...
        'Keys must have the same length.');

    n = length(userPrivateKeyBin); % Length of the keys

    % Initialize the shared key
    sharedKey = zeros(1, n, 'uint8');

    % Generate the shared key
    for i = 1:n
        % Use XOR operation to combine corresponding bits from private and public keys
        sharedKey(i) = bitxor(userPrivateKeyBin(i), dataHolderPublicKeyBin(i));  
    end

    % Convert the shared key to a string for display
    sharedKeyStr = mat2str(sharedKey);

    % Update the UI with the generated shared key
    app.SharedKeyEditField.Value = sharedKeyStr;
end

        end

        % Button pushed function: uploadtocloudButton
        function uploadtocloudButtonPushed(app, event)
          % Check if a file has been encrypted and selected from ListBox_2
    if isempty(app.ListBox_2.UserData) || ~isfield(app.ListBox_2.UserData, 'encryptedFilePath')
        uialert(app.UIFigure, 'Please encrypt a file first.', 'File Needed');
        return;
    end

    % Path to the AWS CLI executable
    awsCliPath = '"C:\Program Files\Amazon\AWSCLIV2\aws"';

    % AWS S3 bucket name
    bucketName = 'shaakthi';

    % Get the path of the encrypted file from ListBox_2 UserData
    encryptedFilePath = app.ListBox_2.UserData.encryptedFilePath;

    % Construct the AWS CLI command to upload the encrypted file to S3
    command = [awsCliPath, ' s3 cp "', encryptedFilePath, '" s3://', bucketName, '/'];

    % Execute the AWS CLI command using system
    [status, output] = system(command);

    % Check if the command executed successfully
    if status == 0
        % Display a message indicating successful upload
        disp(['Encrypted file uploaded successfully to S3 bucket ', bucketName]);
    else
        % Display an error message
        disp('Error uploading encrypted file to S3 bucket');
        disp(output); % Display the output of the AWS CLI command for debugging purposes
    end
        end

        % Button pushed function: DownloadFileButton
        function DownloadFileButtonPushed(app, event)
         % Check if a file has been selected in ListBox_3
    if isempty(app.ListBox_3.Items)
        uialert(app.UIFigure, 'Please select a file first.', 'File Needed');
        return;
    end

    % Get the selected file name from ListBox_3
    selectedFileNameWithTimestamp = app.ListBox_3.Value;
    
    % Parse out the actual file name from the timestamped string
    [~, selectedFileName, ext] = fileparts(selectedFileNameWithTimestamp);
    selectedFileName = [selectedFileName, ext];

    % Get the S3 URI of the selected file
    s3FilePath = app.ListBox_3.UserData.s3URI;

    % Prompt the user to select a location to save the file
    [saveFileName, saveFilePath] = uiputfile([selectedFileName, '.enc'], 'Save File As');

    % Check if the user canceled the operation
    if isequal(saveFileName, 0) || isequal(saveFilePath, 0)
        disp('User canceled the operation.');
        return;
    end

    % Construct the full path for saving the file
    saveFullPath = fullfile(saveFilePath, saveFileName);

    % Construct the AWS CLI command to download the file from S3
    command = ['aws s3 cp "', s3FilePath, '" "', saveFullPath, '"'];

    try
        % Execute the AWS CLI command using system
        [status, output] = system(command);

        % Check if the command executed successfully
        if status == 0
            % Display success message
            disp('File downloaded successfully.');

            % Store the downloaded file path and name
            app.SelectedFilePath = saveFilePath;
            app.SelectedFileName = saveFileName;

        else
            % Display error message if download fails
            disp('Failed to download the file.');
            disp(output); % Display the output of the AWS CLI command for debugging purposes
        end
    catch exception
        % Display error message if an exception occurs
        disp(['Error downloading the file: ', exception.message]);
    end
            
        end

        % Button pushed function: localdriveButton
        function localdriveButtonPushed(app, event)
      % Prompt the user to select a file from the local drive
    [fileName, ~] = uigetfile({'*.*'}, 'Select a file');
    
    % Check if the user canceled the selection
    if isequal(fileName, 0)
        return; % User canceled file selection
    end
    
    % Display the selected file name in ListBox4
    app.ListBox_4.Items = {fileName};
        end

        % Callback function
        function GetFileButtonPushed(app, event)
           
       
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
       
        end

        % Button pushed function: DisplayDataButton
        function DisplayDataButtonPushed(app, event)
        % Execute the function to update the UI table with S3 files
    updateUITableWithS3Files(app);
        end

        % Size changed function: DataManagementTab
        function updateUITableWithS3Files(app, event)
        % Execute AWS CLI command to list files in the S3 bucket and capture the output
    command = 'aws s3 ls s3://shaakthi/';
    [status, cmdOutput] = system(command);

    % Check if the command executed successfully
    if status == 0
        % Split the command output into lines to extract file names
        fileList = strsplit(cmdOutput, '\n');

        % Remove empty strings from the list
        fileList = fileList(~cellfun('isempty', fileList));
% Update the table with the list of files
fileNames = cell(numel(fileList), 1); % Initialize cell array to store file names
for i = 1:numel(fileList)
    parts = strsplit(fileList{i}, ' ');
    fileNames{i} = parts{end};
end

% Update the table with the list of files
app.UITable.Data = fileNames;

     
    else
        % Display error message if AWS CLI command fails
        disp('Failed to list files in S3 bucket.');
    end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DASHBOARDLabel = uilabel(app.UIFigure);
            app.DASHBOARDLabel.Text = 'DASHBOARD';
            app.NavmenubarDropDownLabel = uilabel(app.UIFigure);
            app.NavmenubarDropDownLabel.Text = 'Nav menu bar ';
            app.NavmenubarDropDown = uidropdown(app.UIFigure);
            app.NavmenubarDropDown.Items = {'Home', 'Data Management', 'Key Management:', 'Account Settings', 'Logout'};
            app.NavmenubarDropDown.ValueChangedFcn = createCallbackFcn(app, @NavmenubarDropDownValueChanged, true);
            app.NavmenubarDropDown.ClickedFcn = createCallbackFcn(app, @NavmenubarDropDownValueChanged, true);
            app.NavmenubarDropDown.Value = 'Home';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Label = uilabel(app.HomeTab);
            app.Label.Text = '';
            app.GeneratekeysButton = uibutton(app.HomeTab, 'push');
            app.GeneratekeysButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratekeysButtonPushed, true);
            app.GeneratekeysButton.Text = 'Generate keys';
            app.GenerateSharedkeyButton = uibutton(app.HomeTab, 'push');
            app.GenerateSharedkeyButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSharedkeyButtonPushed, true);
            app.GenerateSharedkeyButton.Text = 'Generate Shared key';
            app.DownloadDataButton = uibutton(app.HomeTab, 'push');
            app.DownloadDataButton.Text = 'Download Data';
            app.PublicKeyEditFieldLabel = uilabel(app.HomeTab);
            app.PublicKeyEditFieldLabel.Text = 'PublicKey';
            app.PublicKeyEditField = uieditfield(app.HomeTab, 'text');
            app.PrivateKeyEditFieldLabel = uilabel(app.HomeTab);
            app.PrivateKeyEditFieldLabel.Text = 'PrivateKey';
            app.PrivateKeyEditField = uieditfield(app.HomeTab, 'text');
            app.CopyButton = uibutton(app.HomeTab, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.CopyButton_2 = uibutton(app.HomeTab, 'push');
            app.CopyButton_2.ButtonPushedFcn = createCallbackFcn(app, @CopyButton_2Pushed, true);
            app.CopyButton_2.Text = 'Copy';
            app.SharedKeyEditFieldLabel = uilabel(app.HomeTab);
            app.SharedKeyEditFieldLabel.Text = 'SharedKey';
            app.SharedKeyEditField = uieditfield(app.HomeTab, 'text');
            app.StoredataTab = uitab(app.TabGroup);
            app.StoredataTab.Title = 'Store data';
            app.EntersharedkeyTextAreaLabel = uilabel(app.StoredataTab);
            app.EntersharedkeyTextAreaLabel.Text = 'Enter shared key';
            app.EntersharedkeyTextArea = uitextarea(app.StoredataTab);
            app.EntersharedkeyTextArea.Tag = 'SharedKeyTextArea';
            app.EntersharedkeyTextArea.Placeholder = 'enter ';
            app.uploadfileButton = uibutton(app.StoredataTab, 'push');
            app.uploadfileButton.ButtonPushedFcn = createCallbackFcn(app, @uploadfileButtonPushed, true);
            app.uploadfileButton.Text = 'upload file';
            app.ListBox = uilistbox(app.StoredataTab);
            app.ListBox.Items = {'', ''};
            app.ListBox.Value = '';
            app.encryptButton = uibutton(app.StoredataTab, 'push');
            app.encryptButton.ButtonPushedFcn = createCallbackFcn(app, @encryptButtonPushed, true);
            app.encryptButton.Text = 'encrypt';
            app.ListBox_2 = uilistbox(app.StoredataTab);
            app.ListBox_2.Items = {''};
            app.ListBox_2.Value = '';
            app.uploadtocloudButton = uibutton(app.StoredataTab, 'push');
            app.uploadtocloudButton.ButtonPushedFcn = createCallbackFcn(app, @uploadtocloudButtonPushed, true);
            app.uploadtocloudButton.Text = 'upload to cloud';
            app.DownloaddataTab = uitab(app.TabGroup);
            app.DownloaddataTab.Title = 'Download data';
            app.SelectfileButton = uibutton(app.DownloaddataTab, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.EnterSharedKeyTextAreaLabel = uilabel(app.DownloaddataTab);
            app.EnterSharedKeyTextAreaLabel.Text = 'Enter SharedKey';
            app.EnterSharedKeyTextArea = uitextarea(app.DownloaddataTab);
            app.EnterSharedKeyTextArea.Placeholder = 'Enter';
            app.ListBox_3 = uilistbox(app.DownloaddataTab);
            app.ListBox_3.Items = {''};
            app.ListBox_3.Value = '';
            app.DecryptButton = uibutton(app.DownloaddataTab, 'state');
            app.DecryptButton.ValueChangedFcn = createCallbackFcn(app, @DecryptButtonValueChanged, true);
            app.DecryptButton.Text = 'Decrypt';
            app.ListBox_4 = uilistbox(app.DownloaddataTab);
            app.ListBox_4.Items = {'', ''};
            app.ListBox_4.Value = '';
            app.DownloadFileButton = uibutton(app.DownloaddataTab, 'push');
            app.DownloadFileButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadFileButtonPushed, true);
            app.DownloadFileButton.Text = 'DownloadFile';
            app.ListBox_5 = uilistbox(app.DownloaddataTab);
            app.ListBox_5.Items = {''};
            app.ListBox_5.Value = '';
            app.localdriveButton = uibutton(app.DownloaddataTab, 'push');
            app.localdriveButton.ButtonPushedFcn = createCallbackFcn(app, @localdriveButtonPushed, true);
            app.localdriveButton.Text = 'local drive';
            app.DataManagementTab = uitab(app.TabGroup);
            app.DataManagementTab.SizeChangedFcn = createCallbackFcn(app, @updateUITableWithS3Files, true);
            app.DataManagementTab.Title = 'Data Management ';
            app.UITable = uitable(app.DataManagementTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.DisplayDataButton = uibutton(app.DataManagementTab, 'push');
            app.DisplayDataButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayDataButtonPushed, true);
            app.DisplayDataButton.Text = 'DisplayData';
            app.KeyManagementTab = uitab(app.TabGroup);
            app.KeyManagementTab.Title = 'Key Management';
            app.AccountSettingsTab = uitab(app.TabGroup);
            app.AccountSettingsTab.Title = 'Account Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Quantum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end