classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LoginButton             matlab.ui.control.Button
        PASSWORDEditField       matlab.ui.control.EditField
        PASSWORDEditFieldLabel  matlab.ui.control.Label
        USERNAMEEditField       matlab.ui.control.EditField
        USERNAMEEditFieldLabel  matlab.ui.control.Label
        LOGINPAGELabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
         % Get the entered username and password
        username = app.USERNAMEEditField.Value;
        password = app.PASSWORDEditField.Value;

        % Perform validation
        if isValidCredentials(username, password)
            % If credentials are valid, navigate to the dashboard page
            close(app.UIFigure); % Close the login page
            quantumApp = Quantum; % Assuming Quantum is the class name of your dashboard app
            quantumApp.run();
        else
            % If credentials are invalid, display an error message
            disp('Invalid username or password'); % Replace with appropriate action
        end

    % Function to validate the entered credentials
    function isValid = isValidCredentials(username, password)
        % Define the valid username and password
        validUsername = 'shakthi';
        validPassword = 'shakthi456';

        % Check if entered credentials match the valid credentials
        isValid = strcmp(username, validUsername) && strcmp(password, validPassword);
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LOGINPAGELabel = uilabel(app.UIFigure);
            app.LOGINPAGELabel.Text = 'LOGIN PAGE';
            app.USERNAMEEditFieldLabel = uilabel(app.UIFigure);
            app.USERNAMEEditFieldLabel.Text = 'USERNAME';
            app.USERNAMEEditField = uieditfield(app.UIFigure, 'text');
            app.PASSWORDEditFieldLabel = uilabel(app.UIFigure);
            app.PASSWORDEditFieldLabel.Text = 'PASSWORD';
            app.PASSWORDEditField = uieditfield(app.UIFigure, 'text');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end